classdef slicer_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SlicerGUILabel                matlab.ui.control.Label
        LengthmmEditFieldLabel        matlab.ui.control.Label
        LengthmmEditField             matlab.ui.control.NumericEditField
        WidthmmEditFieldLabel         matlab.ui.control.Label
        WidthmmEditField              matlab.ui.control.NumericEditField
        HeightmmEditFieldLabel        matlab.ui.control.Label
        HeightmmEditField             matlab.ui.control.NumericEditField
        VoxelsperLayerLengthwiseEditFieldLabel  matlab.ui.control.Label
        VoxelsperLayerLengthwiseEditField  matlab.ui.control.NumericEditField
        LayerHeightmmEditFieldLabel   matlab.ui.control.Label
        LayerHeightmmEditField        matlab.ui.control.NumericEditField
        VoxelsperLayerWidthwiseEditFieldLabel  matlab.ui.control.Label
        VoxelsperLayerWidthwiseEditField  matlab.ui.control.NumericEditField
        VoxelMarginmmEditFieldLabel   matlab.ui.control.Label
        VoxelMarginmmEditField        matlab.ui.control.NumericEditField
        VoxelPaddingmmEditFieldLabel  matlab.ui.control.Label
        VoxelPaddingmmEditField       matlab.ui.control.NumericEditField
        SubmitButton                  matlab.ui.control.Button
        DefectOriginxCoordEditFieldLabel  matlab.ui.control.Label
        DefectOriginxCoordEditField   matlab.ui.control.NumericEditField
        DefectOriginzCoordEditFieldLabel  matlab.ui.control.Label
        DefectOriginzCoordEditField   matlab.ui.control.NumericEditField
        DefectOriginyCoordEditFieldLabel  matlab.ui.control.Label
        DefectOriginyCoordEditField   matlab.ui.control.NumericEditField
        DefectWidthmmEditFieldLabel   matlab.ui.control.Label
        DefectWidthmmEditField        matlab.ui.control.NumericEditField
        DefectHeightmmEditFieldLabel  matlab.ui.control.Label
        DefectHeightmmEditField       matlab.ui.control.NumericEditField
        DefectLengthmmEditFieldLabel  matlab.ui.control.Label
        DefectLengthmmEditField       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Set default values in field boxes
            app.LengthmmEditField.Value = 30;
            app.WidthmmEditField.Value = 30;
            app.HeightmmEditField.Value = 30;
            app.LayerHeightmmEditField.Value = 0.2;
            app.VoxelsperLayerLengthwiseEditField.Value = 4;
            app.VoxelsperLayerWidthwiseEditField.Value = 4;
            app.VoxelMarginmmEditField.Value = 0.5;
            app.VoxelPaddingmmEditField.Value = 0.5;
            app.DefectOriginxCoordEditField.Value = -1;
            app.DefectOriginyCoordEditField.Value = -1;
            app.DefectOriginzCoordEditField.Value = -1;
            app.DefectWidthmmEditField.Value = -1;
            app.DefectLengthmmEditField.Value = -1;
            app.DefectHeightmmEditField.Value = -1;
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            length = app.LengthmmEditField.Value;
            width = app.WidthmmEditField.Value;
            height = app.HeightmmEditField.Value;
            layer_height = app.LayerHeightmmEditField.Value;
            defect_x_origin = app.DefectOriginxCoordEditField.Value;
            defect_y_origin = app.DefectOriginyCoordEditField.Value;
            defect_z_origin = app.DefectOriginzCoordEditField.Value;
            defect_width = app.DefectWidthmmEditField.Value;
            defect_length = app.DefectLengthmmEditField.Value;
            defect_height = app.DefectHeightmmEditField.Value;
            voxels_per_length = app.VoxelsperLayerLengthwiseEditField.Value;
            voxels_per_width = app.VoxelsperLayerWidthwiseEditField.Value;
            voxel_margin = app.VoxelMarginmmEditField.Value;
            voxel_padding = app.VoxelPaddingmmEditField.Value;
            gen_cube(length, width, height, layer_height, voxels_per_length, voxels_per_width, voxel_margin, voxel_padding);
            if defect_x_origin ~= -1 && defect_y_origin ~= -1 && defect_z_origin ~= -1 && defect_width ~= -1 && defect_length ~= -1 && defect_height ~= -1
                disp("Hello");
               insert_defect('test.gcode', defect_x_origin, defect_y_origin, defect_z_origin, defect_width, defect_length, defect_height, length, width, height);
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SlicerGUILabel = uilabel(app.UIFigure);
            app.SlicerGUILabel.Text = 'Slicer GUI';
            app.LengthmmEditFieldLabel = uilabel(app.UIFigure);
            app.LengthmmEditFieldLabel.Text = 'Length (mm)';
            app.LengthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthmmEditFieldLabel = uilabel(app.UIFigure);
            app.WidthmmEditFieldLabel.Text = 'Width (mm)';
            app.WidthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightmmEditFieldLabel = uilabel(app.UIFigure);
            app.HeightmmEditFieldLabel.Text = 'Height (mm)';
            app.HeightmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoxelsperLayerLengthwiseEditFieldLabel = uilabel(app.UIFigure);
            app.VoxelsperLayerLengthwiseEditFieldLabel.Text = 'Voxels per Layer (Length-wise)';
            app.VoxelsperLayerLengthwiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.LayerHeightmmEditFieldLabel = uilabel(app.UIFigure);
            app.LayerHeightmmEditFieldLabel.Text = 'Layer Height (mm)';
            app.LayerHeightmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoxelsperLayerWidthwiseEditFieldLabel = uilabel(app.UIFigure);
            app.VoxelsperLayerWidthwiseEditFieldLabel.Text = 'Voxels per Layer (Width-wise)';
            app.VoxelsperLayerWidthwiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoxelMarginmmEditFieldLabel = uilabel(app.UIFigure);
            app.VoxelMarginmmEditFieldLabel.Text = 'Voxel Margin (mm)';
            app.VoxelMarginmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoxelPaddingmmEditFieldLabel = uilabel(app.UIFigure);
            app.VoxelPaddingmmEditFieldLabel.Text = 'Voxel Padding (mm)';
            app.VoxelPaddingmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.DefectOriginxCoordEditFieldLabel = uilabel(app.UIFigure);
            app.DefectOriginxCoordEditFieldLabel.Text = 'Defect Origin x Coord';
            app.DefectOriginxCoordEditField = uieditfield(app.UIFigure, 'numeric');
            app.DefectOriginzCoordEditFieldLabel = uilabel(app.UIFigure);
            app.DefectOriginzCoordEditFieldLabel.Text = 'Defect Origin z Coord';
            app.DefectOriginzCoordEditField = uieditfield(app.UIFigure, 'numeric');
            app.DefectOriginyCoordEditFieldLabel = uilabel(app.UIFigure);
            app.DefectOriginyCoordEditFieldLabel.Text = 'Defect Origin y Coord';
            app.DefectOriginyCoordEditField = uieditfield(app.UIFigure, 'numeric');
            app.DefectWidthmmEditFieldLabel = uilabel(app.UIFigure);
            app.DefectWidthmmEditFieldLabel.Text = 'Defect Width (mm)';
            app.DefectWidthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DefectHeightmmEditFieldLabel = uilabel(app.UIFigure);
            app.DefectHeightmmEditFieldLabel.Text = 'Defect Height (mm)';
            app.DefectHeightmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DefectLengthmmEditFieldLabel = uilabel(app.UIFigure);
            app.DefectLengthmmEditFieldLabel.Text = 'Defect Length (mm)';
            app.DefectLengthmmEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = slicer_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end